classdef InterfazGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeriesdeFourierUIFigure    matlab.ui.Figure
        IngresodeDatosPanel        matlab.ui.container.Panel
        NmeroArmonicosSliderLabel  matlab.ui.control.Label
        NmeroArmonicosSlider       matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Callback function
        function FuncinEditFieldValueChanged(app, event)
          
        end

        % Callback function
        function IntervalosEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function NmerodeArmnicosEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function CalcularButtonPushed(app, event)


        end

        % Value changed function: NmeroArmonicosSlider
        function NmeroArmonicosSliderValueChanged(app, event)
            cantidadArmonicos = app.NmeroArmonicosSlider.Value;
            syms t n
            zoom(app.UIAxes, 'on')
            cla(app.UIAxes,'reset')
            hold(app.UIAxes,'on');
            intervaloAnalisis = [-1 0 1];
            x=min(intervaloAnalisis):0.01:max(intervaloAnalisis);
            evalFunIngreso = subs(t,{t},{x});
            plot(app.UIAxes,x,evalFunIngreso,'r');
            Ck=exp(pi*n*t*1i)*((exp(-pi*n*1i)*(pi*n*1i + 1))/(2*n^2*pi^2) + (exp(pi*n*1i)*(pi*n*1i - 1))/(2*n^2*pi^2));
            numArmonicos = ceil(cantidadArmonicos/2);
            fx = 0;               
            for i=-numArmonicos:numArmonicos
                if (i>0) || (i<0)
                    fx = fx + subs(Ck,{n,t},{i,t});
                end
            end
            z=-6:0.01:6;
            evalFuncion = subs(fx,{t},{z});
            plot(app.UIAxes,z,evalFuncion, 'b');
            title(app.UIAxes,sprintf('Aproximación de Fourier: %i términos',ceil(cantidadArmonicos)))
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'t'); 
            ylabel(app.UIAxes,'f(t)')
            hold(app.UIAxes,'off');
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeriesdeFourierUIFigure = uifigure;
            app.SeriesdeFourierUIFigure.Name = 'Series de Fourier';
            app.IngresodeDatosPanel = uipanel(app.SeriesdeFourierUIFigure);
            app.IngresodeDatosPanel.Title = 'Ingreso de Datos';
            app.NmeroArmonicosSliderLabel = uilabel(app.IngresodeDatosPanel);
            app.NmeroArmonicosSliderLabel.Text = 'Número Armonicos';
            app.NmeroArmonicosSlider = uislider(app.IngresodeDatosPanel);
            app.NmeroArmonicosSlider.ValueChangedFcn = createCallbackFcn(app, @NmeroArmonicosSliderValueChanged, true);
            app.UIAxes = uiaxes(app.SeriesdeFourierUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = InterfazGrafica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeriesdeFourierUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeriesdeFourierUIFigure)
        end
    end
end